classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputImage                    matlab.ui.control.Image
        PlatenumbersTextAreaLabel     matlab.ui.control.Label
        PlatenumbersTextArea          matlab.ui.control.TextArea
        BrowseButton                  matlab.ui.control.Button
        CarPlateDetectionusingImageProcessingLabel  matlab.ui.control.Label
        ChoosecarplateimagehereLabel  matlab.ui.control.Label
        DetectionresultLabel          matlab.ui.control.Label
        DetectCarPlateButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            extensions = {'*.jpg;*.jpeg;*.tif;*.png;*.gif','All Image Files'};
            [filename, path] = uigetfile(extensions, 'Open file');

            if (isequal(filename, 0))
                return
            end

            image = imread(fullfile(path, filename));
            app.InputImage.ImageSource = image;
            
        end

        % Button pushed function: DetectCarPlateButton
        function DetectCarPlateButtonPushed(app, event)
            im = app.InputImage.ImageSource;
            [plate_image, noPlate] = detect_plate(im);
            
            app.PlatenumbersTextArea.Value = noPlate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputImage = uiimage(app.UIFigure);
            app.PlatenumbersTextAreaLabel = uilabel(app.UIFigure);
            app.PlatenumbersTextAreaLabel.Text = 'Plate numbers';
            app.PlatenumbersTextArea = uitextarea(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CarPlateDetectionusingImageProcessingLabel = uilabel(app.UIFigure);
            app.CarPlateDetectionusingImageProcessingLabel.Text = 'Car Plate Detection using Image Processing';
            app.ChoosecarplateimagehereLabel = uilabel(app.UIFigure);
            app.ChoosecarplateimagehereLabel.Text = 'Choose car plate image here';
            app.DetectionresultLabel = uilabel(app.UIFigure);
            app.DetectionresultLabel.Text = 'Detection result';
            app.DetectCarPlateButton = uibutton(app.UIFigure, 'push');
            app.DetectCarPlateButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCarPlateButtonPushed, true);
            app.DetectCarPlateButton.Text = 'Detect Car Plate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end